classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Image          matlab.ui.control.Image
        b1             matlab.ui.control.Button
        DropDown       matlab.ui.control.DropDown
        DropDownLabel  matlab.ui.control.Label
        axes4          matlab.ui.control.UIAxes
        axes3          matlab.ui.control.UIAxes
        axes2          matlab.ui.control.UIAxes
        axes1          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: b1
        function b1ButtonPushed(app, event)
            cla(app.axes1,"reset")
            cla(app.axes3,"reset")
            cla(app.axes4,"reset")
            % Configura los parámetros de la grabación
            fs = 44100; % Frecuencia de muestreo en Hz
            duracion = 3; % Duración de la grabación en segundos
            grabadora = audiorecorder(fs, 16, 1, 3); % Crear un objeto de grabación
            disp('Coloca el micrófono cerca de la guitarra y toca una cuerda...');
            recordblocking(grabadora, duracion); % Iniciar la grabación y esperar a que termine
            disp('Grabación completada.');
            play(grabadora);
            
            % Obtener los datos de audio grabados
            audioGrabado = getaudiodata(grabadora);
            N = length(audioGrabado);
            n= 0:N-1;
            plot(app.axes1,n/fs,audioGrabado);

            %señal captada en el dominio del tiempo
            L = 20e-3*fs; %tamaño de la ventana corresponde un tiempo de L/Fs segundos.
            overlap=floor(0.5*L); % es una fracción de L en porcentaje.
            nfft=2^nextpow2(L); % Tamaño de la ventana con ceros adicionados (zero padding).
            % Espectrograma
            specgram(audioGrabado,nfft,fs,hamming(L),overlap); colorbar

            %transformada
            X=fft(audioGrabado);
            k=n;
            fk=k*fs/N;
            % [mm, ii]=max(abs(X1(1:N/2)));
            % (ii-1)*Fs/N;
            %potencia espectral 
            pot=abs(X).^2;
            [a,b]=max(pot);
            fo=fk(b);
            plot(app.axes3,fk(1:N/2),pot(1:N/2))
            xlabel(app.axes3,'frecuencia')
            ylabel(app.axes3,'pot espectral')
            c=linspace(0,5);

            Instrumento = 0;
            Nota=1;
            if Instrumento == 0
                switch Nota
                    case 1 % E
                        freq_afin = 329.6 ;
                        d=329.6*ones(size(c));
                        hold(app.axes4,"on")
                        plot(app.axes4,d,c,'r','lineWidth',2)
                        e=fo*ones(size(c));
                        plot(app.axes4,e,c,'b','lineWidth',2)
                        hold(app.axes4,"off")
                    case 2 % B
                        freq_afin = 246.9;
                    case 3 % G
                        freq_afin = 196;
                    case 4 % D
                        freq_afin = 146.8;
                    case 5 % A
                        freq_afin = 110;
                    case 6 % E
                        freq_afin = 82.4;
                    otherwise
                        disp('seleccionar nota')
                end
            else    
                switch Nota
                    case 1 % E
                        freq_afin = 659.3;
                    case 2 % A
                        freq_afin = 440;
                    case 3 % D
                        freq_afin = 293.7;
                    case 4 % G
                        freq_afin = 196;
                    otherwise
                        disp('seleccionar nota')
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes1 = uiaxes(app.UIFigure);
            title(app.axes1, 'Title')
            xlabel(app.axes1, 'X')
            ylabel(app.axes1, 'Y')
            zlabel(app.axes1, 'Z')
            app.axes2 = uiaxes(app.UIFigure);
            title(app.axes2, 'Title')
            xlabel(app.axes2, 'X')
            ylabel(app.axes2, 'Y')
            zlabel(app.axes2, 'Z')
            app.axes3 = uiaxes(app.UIFigure);
            title(app.axes3, 'Title')
            xlabel(app.axes3, 'X')
            ylabel(app.axes3, 'Y')
            zlabel(app.axes3, 'Z')
            app.axes4 = uiaxes(app.UIFigure);
            title(app.axes4, 'Title')
            xlabel(app.axes4, 'X')
            ylabel(app.axes4, 'Y')
            zlabel(app.axes4, 'Z')
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.b1 = uibutton(app.UIFigure, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1ButtonPushed, true);
            app.b1.Text = 'Grabar';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end